classdef app_police_brutalityl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ButtonGroup3               matlab.ui.container.ButtonGroup
        ButtonGroup4               matlab.ui.container.ButtonGroup
        ShootingsbyRaceButton      matlab.ui.control.RadioButton
        BodycamButton_2            matlab.ui.control.RadioButton
        ThreatLevelMentalIllnessButton_4  matlab.ui.control.RadioButton
        ThreatLevelRaceButton_4    matlab.ui.control.RadioButton
        FleeingRaceButton          matlab.ui.control.RadioButton
        ShootingLocationsButton_2  matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function Button4ValueChanged(app, event)
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Callback function
        function ShootingLocationsButtonPushed(app, event)
            
        end

        % Callback function
        function ShootingsbyRaceButtonPushed(app, event)
            % graphs race overall comparison
        end

        % Callback function
        function PlotButtonPushed(app, event)
            
            
        end

        % Selection changed function: ButtonGroup4
        function ButtonGroup4SelectionChanged(app, event)
            selectedButton = app.ButtonGroup4.SelectedObject;
            load('T.mat');
            date = T(:, 3);
            armed = T(:, 5);
            age = T(:, 6);
            gender = T(:, 7);
            race = T(:, 8);
            city = T(:, 9);
            state = T(:, 10);
            mental_illness = T(:, 11);
            threat_level = T(:, 12);
            flee = T(:, 13);
            body_cam = T(:, 14);
            latitude = T(:, 16);
            fleecell = table2cell(flee);
            racecell = table2cell(race);
            race_string = string(racecell);
            flee_string = string(fleecell);
            tbl_race = tabulate(racecell);
            
            
            y1 = cell2mat(tbl_race(:,2));
            y2 = [354; 3606; 1110; 804; 48; 78];
            
            if app.ShootingsbyRaceButton.Value == true
                % pulling numbers from census data when plotting expected # killed
                % calculated multiplying # in csv by percentage of race in US pop
                % percentage of race in US pop was found not in github (why calc was by
                % hand)
                x = categorical({'Asian', 'White', 'Hisp', 'Black', 'Other', 'Native'});
                y = horzcat(y1,y2);
                bar(x,y)
                legend('Actual # Killed', 'Expected # Killed')
                xlabel('Race')
                ylabel('Number of Fatalities')
                title('Fatalities by Race')
                
            elseif app.FleeingRaceButton.Value == true
                racecell = table2cell(race);
                fleecell = table2cell(flee);
                race_string = string(racecell);
                flee_string = string(fleecell);
                % separates Black people from other races
                for ii = 1:length(race_string)
                    if race_string(ii) ~= "B";
                        race_string(ii) = "O";
                    end
                end
                black_flee = 0;
                other_flee = 0;
                black_not_flee = 0;
                other_not_flee = 0;
                for k = 1:length(flee_string)
                    if flee_string(k) == "fleeing" && race_string(k) == "B"
                        black_flee = black_flee + 1;
                    elseif flee_string(k) == "fleeing" && race_string(k) == "O"
                        other_flee = other_flee + 1;
                    elseif flee_string(k) == "Not fleeing" && race_string(k) == "B"
                        black_not_flee = black_not_flee + 1;
                    elseif flee_string(k) == "Not fleeing" && race_string(k) == "O"
                        other_not_flee = other_not_flee + 1;
                    end
                end
                % bar chart for Black people fleeing vs Other people fleeing
                x = categorical({'Black', 'Other'});
                yes_flee = vertcat(black_flee, other_flee);
                not_flee = vertcat(black_not_flee, other_not_flee);
                y = horzcat(yes_flee, not_flee);
                bar(x,y)
                legend('Flee', 'Not Flee')
                title('Flee vs Not Fleeing Comparison')
                
            elseif app.BodycamButton_2.Value == true
                bodycamcell = table2cell(body_cam);
                tbl_bodycam = tabulate(bodycamcell);
                counter = 0;
                for ii = 1:length(bodycamcell) % xx = 1: length(numlist), numlist = list of bodycam variables
                    string1 = bodycamcell(ii);
                    if strcmp('False', string1)
                        counter = counter + 1; % count number of false
                    else
                        counter = counter + 1; % count number of true
                    end
                end
                % pie chart showing statistical data for body cam
                X = cell2mat(tbl_bodycam(:,2));
                labels = {'No Body Cam', 'Body Cam'};
                pie(X,labels)
                title('Body cams on policemen')


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ButtonGroup3 = uibuttongroup(app.UIFigure);
            app.ButtonGroup3.Title = 'Button Group3';
            app.ButtonGroup4 = uibuttongroup(app.UIFigure);
            app.ButtonGroup4.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup4SelectionChanged, true);
            app.ButtonGroup4.Title = 'ButtonGroup4';
            app.ShootingsbyRaceButton = uiradiobutton(app.ButtonGroup4);
            app.ShootingsbyRaceButton.Text = 'Shootings by Race';
            app.ShootingsbyRaceButton.Value = true;
            app.BodycamButton_2 = uiradiobutton(app.ButtonGroup4);
            app.BodycamButton_2.Text = 'Bodycam';
            app.ThreatLevelMentalIllnessButton_4 = uiradiobutton(app.ButtonGroup4);
            app.ThreatLevelMentalIllnessButton_4.Text = 'Threat Level/ Mental Illness';
            app.ThreatLevelRaceButton_4 = uiradiobutton(app.ButtonGroup4);
            app.ThreatLevelRaceButton_4.Text = 'Threat Level/ Race';
            app.FleeingRaceButton = uiradiobutton(app.ButtonGroup4);
            app.FleeingRaceButton.Text = 'Fleeing/ Race';
            app.ShootingLocationsButton_2 = uiradiobutton(app.ButtonGroup4);
            app.ShootingLocationsButton_2.Text = 'Shooting Locations';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_police_brutalityl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end